classdef NeuroChannelSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowLayoutUIFigure    matlab.ui.Figure
        Toolbar               matlab.ui.container.Toolbar
        PushTool              matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        LayoutPanel           matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        MissingChannelsField  matlab.ui.control.EditField
        MissingchannelsLabel  matlab.ui.control.Label
        GridLayout4           matlab.ui.container.GridLayout
        OkButton              matlab.ui.control.Button
        TopographyPanel       matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputChannels % Description
        SelectedChannels
        handle % Description
        defaultlocs
        chanlocs
    end

    
    methods (Access = private)
        
        function init(app, InputChannels, SelectedChannels)
            
            % Get InputChannels and SelectedChannels
            app.InputChannels      = InputChannels';
            app.SelectedChannels   = SelectedChannels;

            % Import default chanlocs
            app.defaultlocs = load('chanlocs64.mat');
            

            % Set table properties
            addStyle(app.UITable, uistyle('HorizontalAlignment','center'));

            % Set axes properties  
            title(app.UIAxes, '');
        end
        
        function populate(app)

            index    = (1:length(app.InputChannels))';
            
            app.UITable.Data = table(index, app.InputChannels', app.SelectedChannels');
          
        end
        
        function index = index_selected_channels(app)
            [~, ~, index] = intersect(lower(app.SelectedChannels), lower({app.defaultlocs.chanlocs(:).labels}), 'stable');
        end

        function h = show_channel_layout(app)
            

            app.ShowLayoutUIFigure.HandleVisibility = 'callback';
            h = topoplot([],app.chanlocs, 'style', 'blank', 'electrodes', 'ptslabels', 'headrad', 'rim');
            axis image;
            delete(app.UIAxes.Children(end-1:end));
            title(app.UIAxes, '');
            set(app.UIAxes.Children(1:length(app.chanlocs)), 'FontSize', 12);
            app.ShowLayoutUIFigure.HandleVisibility = 'off';
        end
        
        function update_missing_channels(app)
            [~, missIdx] = setdiff(lower(app.SelectedChannels), lower({app.defaultlocs.chanlocs(:).labels}));
            misschans = app.SelectedChannels(missIdx);
            app.MissingChannelsField.Value = strjoin(misschans, ', ');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InputChannels, SelectedChannels)

            app.init(InputChannels, SelectedChannels);
            app.populate(); 

            index = app.index_selected_channels();
            app.update_missing_channels();
            app.chanlocs = app.defaultlocs.chanlocs(index);

            app.handle = app.show_channel_layout();
            
             
        end

        % Close request function: ShowLayoutUIFigure
        function ShowLayoutUIFigureCloseRequest(app, event)
            %app.MainApp.LayoutDropDown.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShowLayoutUIFigure = uifigure('Visible', 'off');
            app.ShowLayoutUIFigure.Name = 'Show Layout';
            app.ShowLayoutUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShowLayoutUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.ShowLayoutUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.ShowLayoutUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TopographyPanel = uipanel(app.GridLayout2);
            app.TopographyPanel.Title = 'Topography:';
            app.UIAxes = uiaxes(app.TopographyPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.OkButton = uibutton(app.GridLayout4, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.MissingchannelsLabel = uilabel(app.GridLayout6);
            app.MissingchannelsLabel.Text = 'Missing channels:';
            app.MissingChannelsField = uieditfield(app.GridLayout6, 'text');
            app.MissingChannelsField.Editable = 'off';
            app.LayoutPanel = uipanel(app.GridLayout);
            app.LayoutPanel.Title = 'Layout:';
            app.GridLayout5 = uigridlayout(app.LayoutPanel);
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = {'#'; 'Input Channels'; 'Selected Labels'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.ShowLayoutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroChannelSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShowLayoutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowLayoutUIFigure)
        end
    end
end