classdef NeuroFileSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        SelectedFilesBox              matlab.ui.control.ListBox
        SelectedfilesListBoxLabel     matlab.ui.control.Label
        BrowserBox                    matlab.ui.control.ListBox
        CurrentdirectoryListBoxLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    
    methods (Access = private)
        
       function browser_box_list(comp, path)
     
            entries = dir(path);
            names = {entries.name}; 
            index  = 1:length(entries);
            rmindx = find(contains('.', names));
            entries = entries(setdiff(index, rmindx));
            comp.BrowserBox.Items = {entries.name};
            comp.BrowserBox.ItemsData = entries;
            comp.BrowserBox.Value = {};
            
        end
    end
    
    methods (Access = public)
        
        function reset_selected(comp)
            comp.SelectedFilesBox.Items = {};
            comp.SelectedFilesBox.ItemsData = {};
            notify(comp, 'ValueChanged');
        end
        
        function files = get_selected(comp)
            files = comp.SelectedFilesBox.ItemsData; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
            % Browser box
            browser_box_list(comp, pwd);

        end

        % Double-clicked callback: BrowserBox
        function BrowserBoxDoubleClicked(comp, event)
            item = event.InteractionInformation.Item;
            selItem = comp.BrowserBox.ItemsData(item);

            pathItem = fullfile(selItem.folder, selItem.name);

            if(selItem.isdir)
                browser_box_list(comp, pathItem);
            else
                [~, ~, ext] = fileparts(pathItem);

                if(strcmpi(ext, '.gdf'))
                    % Add to selected box
                    if any(strcmpi(comp.SelectedFilesBox.Items, selItem.name)) == false
                        comp.SelectedFilesBox.Items{end+1} = selItem.name;
                        comp.SelectedFilesBox.ItemsData{end+1} = pathItem;
                        notify(comp, 'ValueChanged');
                    end
                end
            end
        end

        % Double-clicked callback: SelectedFilesBox
        function SelectedFilesBoxDoubleClicked(comp, event)
            item = event.InteractionInformation.Item;
            index  = 1:length(comp.SelectedFilesBox.Items);
            rmindx = setdiff(index, item);
            comp.SelectedFilesBox.Items = comp.SelectedFilesBox.Items(rmindx);
            comp.SelectedFilesBox.ItemsData = comp.SelectedFilesBox.ItemsData(rmindx);
            notify(comp, 'ValueChanged');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 348 152];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.CurrentdirectoryListBoxLabel = uilabel(comp.GridLayout);
            comp.CurrentdirectoryListBoxLabel.Text = 'Current directory:';
            comp.BrowserBox = uilistbox(comp.GridLayout);
            comp.BrowserBox.Items = {};
            comp.BrowserBox.Multiselect = 'on';
            comp.BrowserBox.Tag = 'browserbox';
            comp.BrowserBox.DoubleClickedFcn = matlab.apps.createCallbackFcn(comp, @BrowserBoxDoubleClicked, true);
            comp.BrowserBox.Value = {};
            comp.SelectedfilesListBoxLabel = uilabel(comp.GridLayout);
            comp.SelectedfilesListBoxLabel.Text = 'Selected file(s):';
            comp.SelectedFilesBox = uilistbox(comp.GridLayout);
            comp.SelectedFilesBox.Items = {};
            comp.SelectedFilesBox.DoubleClickedFcn = matlab.apps.createCallbackFcn(comp, @SelectedFilesBoxDoubleClicked, true);
            comp.SelectedFilesBox.Value = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end