classdef NeuroTrainSmr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        TrainButton                     matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        LayoutPanel                     matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        LayoutShow                      matlab.ui.control.Button
        LayoutDropDown                  matlab.ui.control.DropDown
        DatainfoPanel                   matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        SubjectIdEditField              matlab.ui.control.EditField
        SubjectIdEditFieldLabel         matlab.ui.control.Label
        SelectedchannelsEditField       matlab.ui.control.EditField
        SelectedchannelsEditFieldLabel  matlab.ui.control.Label
        DatachannelsEditField           matlab.ui.control.NumericEditField
        DatachannelsEditFieldLabel      matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.NumericEditField
        SampleRateHzLabel               matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        TrainonalldataCheckBox          matlab.ui.control.CheckBox
        RecomputePSDCheckBox            matlab.ui.control.CheckBox
        SavePSDCheckBox                 matlab.ui.control.CheckBox
        SpectrogramPanel                matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        averageEditField                matlab.ui.control.NumericEditField
        averagesEditFieldLabel          matlab.ui.control.Label
        innershiftEditField             matlab.ui.control.NumericEditField
        innershiftsEditFieldLabel       matlab.ui.control.Label
        outershiftEditField             matlab.ui.control.NumericEditField
        outershiftsEditFieldLabel       matlab.ui.control.Label
        windowlengthEditField           matlab.ui.control.NumericEditField
        windowlengthsEditFieldLabel     matlab.ui.control.Label
        PreprocessingPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        LaplacianCheckBox               matlab.ui.control.CheckBox
        CARCheckBox                     matlab.ui.control.CheckBox
        DCremovalCheckBox               matlab.ui.control.CheckBox
        GridLayout6                     matlab.ui.container.GridLayout
        NeuroFileSelector               NeuroFileSelector
    end

    
    properties (Access = public)
        settings % Settings for the train
    end
    
    properties (Access = private)
        ChannelBox % Description
    end
    
    methods (Access = private)
        
        function init_gui_components(app, settings)
            app.DCremovalCheckBox.Value = settings.processing.filters.dc.enable;
            app.CARCheckBox.Value       = settings.processing.filters.car.enable;
            app.LaplacianCheckBox.Value = settings.processing.filters.laplacian.enable;

            app.averageEditField.Value      = settings.processing.psd.mlength;
            app.outershiftEditField.Value   = settings.processing.psd.wshift;
            app.innershiftEditField.Value   = settings.processing.psd.pshift;
            app.windowlengthEditField.Value = settings.processing.psd.wlength;

            app.SavePSDCheckBox.Value      = settings.storage.save;
            app.RecomputePSDCheckBox.Value = settings.storage.recompute;

            app.TrainonalldataCheckBox.Value = settings.classification.train_on_all; 

            app.SampleRateHzEditField.Value     = 0;
            app.DatachannelsEditField.Value     = 0;
            app.SampleRateHzEditField.FontColor = 'k';           
            app.DatachannelsEditField.FontColor = 'k'; 
            app.DatachannelsEditField.Tooltip   = '';
            app.SampleRateHzEditField.Tooltip   = '';

            app.LayoutDropDown.Enable = 'off';
            app.LayoutShow.Enable     = 'off';

            montages = neurodata_get_montages('./config/layouts/');
            app.LayoutDropDown.Items = {montages(:).name};
            app.LayoutDropDown.ItemsData = montages;
        end
        
        
        function info = get_gdf_info(~, files)

            info = [];

            if isempty(files) 
                return
            end

            
            for fId = 1:length(files) 
                h = neuroio_import_header(files{fId});
                cinfo.samplerate = h.SampleRate;
                cinfo.labels = h.Label;
                info = cat(1, info, cinfo);
            end

        end
        
        function reset_data_info(app)
            app.SampleRateHzEditField.Value = 0;
            app.SampleRateHzEditField.FontColor = 'k'; 
            app.DatachannelsEditField.Value = 0;
            app.DatachannelsEditField.FontColor = 'k';
            app.LayoutDropDown.Enable = 'off';
            app.LayoutShow.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Load default settings
            app.settings = neuro_smr_default_settings;

            % Initialize GUI elements with default settings
            init_gui_components(app, app.settings);
        end

        % Value changed function: averageEditField
        function averageEditFieldValueChanged(app, event)
            app.settings.processing.psd.mlength = app.averageEditField.Value;
        end

        % Value changed function: innershiftEditField
        function innershiftEditFieldValueChanged(app, event)
            app.settings.processing.psd.pshift = app.innershiftEditField.Value;           
        end

        % Value changed function: outershiftEditField
        function outershiftEditFieldValueChanged(app, event)
            app.settings.processing.psd.wshift = app.outershiftEditField.Value;
        end

        % Value changed function: windowlengthEditField
        function windowlengthEditFieldValueChanged(app, event)
            app.settings.processing.psd.wlength = app.windowlengthEditField.Value;        
        end

        % Value changed function: RecomputePSDCheckBox
        function RecomputePSDCheckBoxValueChanged(app, event)
            app.settings.storage.recompute = app.RecomputePSDCheckBox.Value;
        end

        % Value changed function: SavePSDCheckBox
        function SavePSDCheckBoxValueChanged(app, event)
            app.settings.storage.save = app.SavePSDCheckBox.Value;
        end

        % Value changed function: LaplacianCheckBox
        function LaplacianCheckBoxValueChanged(app, event)
            app.settings.processing.filters.laplacian.enable = app.LaplacianCheckBox.Value;
        end

        % Value changed function: CARCheckBox
        function CARCheckBoxValueChanged(app, event)
            app.settings.processing.filters.car.enable = app.CARCheckBox.Value;
        end

        % Value changed function: DCremovalCheckBox
        function DCremovalCheckBoxValueChanged(app, event)
            app.settings.processing.filters.dc.enable = app.DCremovalCheckBox.Value;
        end

        % Value changed function: TrainonalldataCheckBox
        function TrainonalldataCheckBoxValueChanged(app, event)
            app.settings.classification.train_on_all = app.TrainonalldataCheckBox.Value;           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Load default settings
            app.settings = neuro_smr_default_settings;

            % Initialize GUI elements with default settings
            init_gui_components(app, app.settings);

            % Reset selected files in browser
            app.NeuroFileSelector.reset_selected();        
        end

        % Callback function: NeuroFileSelector
        function browserValueChanged(app, event)
            files = app.NeuroFileSelector.get_selected();
            

            info = app.get_gdf_info(files);

            if isempty(info) 
               app.reset_data_info()
                return
            end
            
            if length(unique([info(:).samplerate])) == 1
                app.SampleRateHzEditField.Value = unique([info(:).samplerate]);
                app.SampleRateHzEditField.FontColor = 'k';  
                app.SampleRateHzEditField.Tooltip = '';
                app.settings.data.samplerate = unique([info(:).samplerate]);
            else 
                app.SampleRateHzEditField.FontColor = 'r';
                app.SampleRateHzEditField.Tooltip = 'Selected files have different sampling rate';
                app.settings.data.samplerate = nan;
            end

            if length(info) == 1
                app.DatachannelsEditField.Value = length(info(1).labels);
                app.DatachannelsEditField.FontColor = 'k'; 
                app.DatachannelsEditField.Tooltip = '';
                app.settings.data.channels.raw = info(1).labels;
                app.LayoutDropDown.Enable = 'on';
                app.LayoutShow.Enable = 'on';
            elseif isequal(info(:).labels) == true
                app.DatachannelsEditField.Value = length(info(1).labels);
                app.DatachannelsEditField.FontColor = 'k'; 
                app.DatachannelsEditField.Tooltip = '';
                app.settings.data.channels.raw = info(1).labels;
                app.LayoutDropDown.Enable = 'on';
                app.LayoutShow.Enable = 'on';
            else 
                app.DatachannelsEditField.FontColor = 'r';
                app.DatachannelsEditField.Tooltip = 'Selected files have different channels';
                app.settings.data.channels.raw = nan;
                app.LayoutDropDown.Enable = 'off';
                app.LayoutShow.Enable = 'off';
            end
            
        end

        % Clicked callback: LayoutDropDown
        function LayoutDropDownClicked(app, event)
      

        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(app, event)
            
            
        end

        % Button pushed function: LayoutShow
        function LayoutShowButtonPushed(app, event)
%             tmplabels = {'FP1', 'FP2', 'FZ', 'FC5', 'FC1', 'FC2', 'FC6',  'C3',  'CZ', ... 
%                          'C4', 'CP5', 'CP1', 'CP2', 'CP6',  'P3', 'Pz',  'P4',  'F1', ... 
%                          'F2', 'FC3', 'FCZ', 'FC4', 'C5',  'C1',  'C2',  'C6', 'CP3', ...
%                          'CP4',  'P5',  'P1', 'P2',  'P6', 'Status'};
%             tmplayout = '';


            montage = app.LayoutDropDown.Value;

            app.ChannelBox = NeuroChannelSelector(montage.labels, montage.name);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.NeuroFileSelector = NeuroFileSelector(app.GridLayout6);
            app.NeuroFileSelector.ValueChangedFcn = createCallbackFcn(app, @browserValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.PreprocessingPanel = uipanel(app.GridLayout7);
            app.PreprocessingPanel.Title = 'Pre-processing';
            app.GridLayout3 = uigridlayout(app.PreprocessingPanel);
            app.DCremovalCheckBox = uicheckbox(app.GridLayout3);
            app.DCremovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCremovalCheckBoxValueChanged, true);
            app.DCremovalCheckBox.Text = 'DC removal';
            app.CARCheckBox = uicheckbox(app.GridLayout3);
            app.CARCheckBox.ValueChangedFcn = createCallbackFcn(app, @CARCheckBoxValueChanged, true);
            app.CARCheckBox.Text = 'CAR';
            app.LaplacianCheckBox = uicheckbox(app.GridLayout3);
            app.LaplacianCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaplacianCheckBoxValueChanged, true);
            app.LaplacianCheckBox.Enable = 'off';
            app.LaplacianCheckBox.Text = 'Laplacian';
            app.SpectrogramPanel = uipanel(app.GridLayout7);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.GridLayout4 = uigridlayout(app.SpectrogramPanel);
            app.windowlengthsEditFieldLabel = uilabel(app.GridLayout4);
            app.windowlengthsEditFieldLabel.Text = 'window length [s]:';
            app.windowlengthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.windowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @windowlengthEditFieldValueChanged, true);
            app.outershiftsEditFieldLabel = uilabel(app.GridLayout4);
            app.outershiftsEditFieldLabel.Text = 'outer shift [s]:';
            app.outershiftEditField = uieditfield(app.GridLayout4, 'numeric');
            app.outershiftEditField.ValueChangedFcn = createCallbackFcn(app, @outershiftEditFieldValueChanged, true);
            app.innershiftsEditFieldLabel = uilabel(app.GridLayout4);
            app.innershiftsEditFieldLabel.Text = 'inner shift [s]:';
            app.innershiftEditField = uieditfield(app.GridLayout4, 'numeric');
            app.innershiftEditField.ValueChangedFcn = createCallbackFcn(app, @innershiftEditFieldValueChanged, true);
            app.averagesEditFieldLabel = uilabel(app.GridLayout4);
            app.averagesEditFieldLabel.Text = 'average [s]:';
            app.averageEditField = uieditfield(app.GridLayout4, 'numeric');
            app.averageEditField.ValueChangedFcn = createCallbackFcn(app, @averageEditFieldValueChanged, true);
            app.OptionsPanel = uipanel(app.GridLayout7);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout5 = uigridlayout(app.OptionsPanel);
            app.SavePSDCheckBox = uicheckbox(app.GridLayout5);
            app.SavePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePSDCheckBoxValueChanged, true);
            app.SavePSDCheckBox.Text = 'Save PSD';
            app.RecomputePSDCheckBox = uicheckbox(app.GridLayout5);
            app.RecomputePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputePSDCheckBoxValueChanged, true);
            app.RecomputePSDCheckBox.Text = 'Recompute PSD';
            app.TrainonalldataCheckBox = uicheckbox(app.GridLayout5);
            app.TrainonalldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainonalldataCheckBoxValueChanged, true);
            app.TrainonalldataCheckBox.Text = 'Train on all data';
            app.DatainfoPanel = uipanel(app.GridLayout7);
            app.DatainfoPanel.Title = 'Data info';
            app.GridLayout9 = uigridlayout(app.DatainfoPanel);
            app.SampleRateHzLabel = uilabel(app.GridLayout9);
            app.SampleRateHzLabel.Text = 'Sample Rate [Hz]:';
            app.SampleRateHzEditField = uieditfield(app.GridLayout9, 'numeric');
            app.SampleRateHzEditField.Editable = 'off';
            app.DatachannelsEditFieldLabel = uilabel(app.GridLayout9);
            app.DatachannelsEditFieldLabel.Text = 'Data channels:';
            app.DatachannelsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.DatachannelsEditField.Editable = 'off';
            app.SelectedchannelsEditFieldLabel = uilabel(app.GridLayout9);
            app.SelectedchannelsEditFieldLabel.Text = 'Selected channels:';
            app.SelectedchannelsEditField = uieditfield(app.GridLayout9, 'text');
            app.SelectedchannelsEditField.Editable = 'off';
            app.SubjectIdEditFieldLabel = uilabel(app.GridLayout9);
            app.SubjectIdEditFieldLabel.Text = 'Subject Id:';
            app.SubjectIdEditField = uieditfield(app.GridLayout9, 'text');
            app.SubjectIdEditField.Editable = 'off';
            app.LayoutPanel = uipanel(app.GridLayout7);
            app.LayoutPanel.Title = 'Layout:';
            app.GridLayout10 = uigridlayout(app.LayoutPanel);
            app.LayoutDropDown = uidropdown(app.GridLayout10);
            app.LayoutDropDown.Items = {};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownValueChanged, true);
            app.LayoutDropDown.Placeholder = 'Select....';
            app.LayoutDropDown.ClickedFcn = createCallbackFcn(app, @LayoutDropDownClicked, true);
            app.LayoutDropDown.Value = {};
            app.LayoutShow = uibutton(app.GridLayout10, 'push');
            app.LayoutShow.ButtonPushedFcn = createCallbackFcn(app, @LayoutShowButtonPushed, true);
            app.LayoutShow.Text = 'Show';
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ProcessButton = uibutton(app.GridLayout7, 'push');
            app.ProcessButton.Text = 'Process';
            app.TrainButton = uibutton(app.GridLayout7, 'push');
            app.TrainButton.Text = 'Train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroTrainSmr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end