classdef FeatureSelectionSmr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TopGridMain  matlab.ui.container.GridLayout
    end

    
    properties (Access = public)
        TopGrid matlab.ui.container.GridLayout % Description
    end

    properties (Access = private)
        nruns
    end
    
    methods (Access = private)
        
        function app = set_topgrid_size(app, ncols, mincolwidth, maxcolwidth)

            if isempty(app.TopGrid) == true
                return;
            end%%%
            

            % if ncols < 4
                figw = app.UIFigure.Position(3);
                figwmin = ncols*mincolwidth + app.TopGrid.ColumnSpacing*(ncols - 1) + sum(app.TopGridMain.Padding([1 3]));
                padding = (figw - figwmin)/(2);
                if padding > 0
                    app.TopGrid.Padding([1 3]) = padding;
                    %app.TopGrid.ColumnSpacing = padding;
                end
            % end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            data = randn(23, 16, 4);
            app.nruns = size(data, 3);
           
            
            app.TopGrid = uigridlayout(app.TopGridMain, [1 app.nruns]);
            app.TopGrid.Scrollable ="on";
            app.TopGrid.ColumnSpacing = 40;
            app.TopGrid.RowHeight = {'1x'};
            app.TopGrid.ColumnWidth = repmat(400, app.nruns, 1);
            
            app.set_topgrid_size(app.nruns, 400, 500);


            %keyboard
            for i = 1:app.nruns
                %ax = uiaxes(app.TopGrid);
                comp = FeaturesMap(app.TopGrid);
                ax = comp.init(randn(16, 23));
                
                ax.Layout.Column = i;
                ax.Layout.Row = 1;
            end
               
            %app.GridLayout2.Layout. 
            keyboard

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            
            app.set_topgrid_size(app.nruns, 400, 500);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TopGridMain = uigridlayout(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeatureSelectionSmr(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end