classdef NeuroTrainSmr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Toolbar                   matlab.ui.container.Toolbar
        PushTool                  matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        GridLayout24              matlab.ui.container.GridLayout
        GridLayout25              matlab.ui.container.GridLayout
        TrainButton               matlab.ui.control.Button
        ProcessButton             matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        FileSelector              FileSelector
        GridLayout6               matlab.ui.container.GridLayout
        GridLayout23              matlab.ui.container.GridLayout
        FeaturesSelectionOptions  FeaturesSelectionOptions
        DecoderSelector           DecoderSelector
        OptionsPanel              matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        TrainonalldataCheckBox    matlab.ui.control.CheckBox
        RecomputePSDCheckBox      matlab.ui.control.CheckBox
        SavePSDCheckBox           matlab.ui.control.CheckBox
        GridLayout22              matlab.ui.container.GridLayout
        SpectrogramOptions        SpectrogramOptions
        PreprocessingOptions      PreprocessingOptions
        TaskSelector              TaskSelector
    end

    
    properties (Access = public)
        settings                % settings for the train
        options
        action      = false;    % Description
    end
    
    properties (Access = private)
        default_save_psd       = true;
        default_recompute_psd  = true;
        default_train_on_all   = true;
        default_action_type    = '';
    end
    
    methods (Access = private)
        
        function init(app)
            app.set_action_type(app.default_action_type); 
            app.set_recompute_psd(app.default_recompute_psd);
            app.set_save_psd(app.default_save_psd);
            app.set_train_on_all(app.default_train_on_all);       
        end

        function set_action_type(app, value)
            app.options.action = value;
        end

        function set_save_psd(app, value)
            app.options.save_psd = value;
            app.SavePSDCheckBox.Value            = value;
        end

        function set_recompute_psd(app, value)
            app.options.recompute_psd  = value;
            app.RecomputePSDCheckBox.Value             = value;
        end

        function set_train_on_all(app, value)
            app.options.train_on_all = value;
            app.TrainonalldataCheckBox.Value         = value;
        end
  
    end
    
    methods (Access = public)
        
        function reset(app)
            
            % Reset components
            app.FileSelector.reset();      
            app.PreprocessingOptions.reset();
            app.SpectrogramOptions.reset();
            app.TaskSelector.reset();
            app.FeaturesSelectionOptions.reset();
            app.DecoderSelector.reset();

            app.set_save_psd(app.default_save_psd);
            app.set_recompute_psd(app.default_recompute_psd);
            app.set_train_on_all(app.default_train_on_all);
            
        end

        function settings = retrieve(app)
            settings.files                 = app.FileSelector.getfiles();
            settings.options.preprocessing = app.PreprocessingOptions.retrieve();
            settings.options.spectrogram   = app.SpectrogramOptions.retrieve();
            settings.options.taskset       = app.TaskSelector.retrieve();
            settings.options.selection     = app.FeaturesSelectionOptions.retrieve();
            settings.options.decoder       = app.DecoderSelector.retrieve();
            settings.options.common        = app.options;
            
            settings.options = orderfields(settings.options);
            settings         = orderfields(settings);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.init();
        end

        % Value changed function: RecomputePSDCheckBox
        function RecomputePSDCheckBoxValueChanged(app, event)
            app.set_recompute_psd(app.SavePSDCheckBox.Value)
        end

        % Value changed function: SavePSDCheckBox
        function SavePSDCheckBoxValueChanged(app, event)
            app.set_save_psd(app.SavePSDCheckBox.Value);
        end

        % Value changed function: TrainonalldataCheckBox
        function TrainonalldataCheckBoxValueChanged(app, event)
            app.set_train_on_all(app.TrainonalldataCheckBox.Value);           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset();
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.set_action_type('train');
            app.settings = app.retrieve();
            app.action = true;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.set_action_type('process');
            app.settings = app.retrieve();
            app.action = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout22 = uigridlayout(app.GridLayout6);
            app.TaskSelector = TaskSelector(app.GridLayout22);
            app.PreprocessingOptions = PreprocessingOptions(app.GridLayout22);
            app.SpectrogramOptions = SpectrogramOptions(app.GridLayout22);
            app.GridLayout23 = uigridlayout(app.GridLayout6);
            app.OptionsPanel = uipanel(app.GridLayout23);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout5 = uigridlayout(app.OptionsPanel);
            app.SavePSDCheckBox = uicheckbox(app.GridLayout5);
            app.SavePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePSDCheckBoxValueChanged, true);
            app.SavePSDCheckBox.Text = 'Save PSD';
            app.RecomputePSDCheckBox = uicheckbox(app.GridLayout5);
            app.RecomputePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecomputePSDCheckBoxValueChanged, true);
            app.RecomputePSDCheckBox.Text = 'Recompute PSD';
            app.TrainonalldataCheckBox = uicheckbox(app.GridLayout5);
            app.TrainonalldataCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainonalldataCheckBoxValueChanged, true);
            app.TrainonalldataCheckBox.Text = 'Train on all data';
            app.DecoderSelector = DecoderSelector(app.GridLayout23);
            app.FeaturesSelectionOptions = FeaturesSelectionOptions(app.GridLayout23);
            app.FileSelector = FileSelector(app.GridLayout);
            app.GridLayout24 = uigridlayout(app.GridLayout);
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.ResetButton = uibutton(app.GridLayout25, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ProcessButton = uibutton(app.GridLayout25, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TrainButton = uibutton(app.GridLayout25, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroTrainSmr

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end