classdef ChannelsLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowLayoutUIFigure    matlab.ui.Figure
        Toolbar               matlab.ui.container.Toolbar
        PushTool              matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        LayoutPanel           matlab.ui.container.Panel
        GridLayout5           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        GridLayout2           matlab.ui.container.GridLayout
        GridLayout6           matlab.ui.container.GridLayout
        MissingChannelsField  matlab.ui.control.EditField
        MissingchannelsLabel  matlab.ui.control.Label
        GridLayout4           matlab.ui.container.GridLayout
        OkButton              matlab.ui.control.Button
        TopographyPanel       matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        channels % Description
        montage
        missing
        handle % Description
        defaultlocs
    end

    
    methods (Access = private)
        
        function init(app, channels, montage)
            
            % Get InputChannels and SelectedChannels
            app.channels  = channels;
            app.montage   = montage;

            % Import default chanlocs
            app.defaultlocs = load('chanlocs64.mat');
            
            % Set table properties
            addStyle(app.UITable, uistyle('HorizontalAlignment','center'));

            % Set axes properties  
            title(app.UIAxes, '');
        end
        
        function populate(app)

            index = (1:length(app.channels))';
            fullmontage = repmat({''}, length(app.channels), 1);

            [~, foundInMontage, foundInChannels] = intersect(lower(app.montage), lower(app.channels),  'stable');
            
            % If cannot find any electrode in montage also in the input
            % channels, I just make a simple association. Otherwise I match
            % the channels
            if(isempty(foundInChannels) == true)
                fullmontage(1:length(app.montage)) = app.montage;
            else
                fullmontage(foundInChannels) = app.montage(foundInMontage); 
                % Check for missing channels
                app.missing = setdiff(lower(app.montage), lower(app.montage(foundInMontage)), 'stable');
            end

            
            app.UITable.Data = table(index, app.channels, fullmontage);
          
        end

        function h = show_channel_layout(app)
            
            cchannels = setdiff(lower(app.montage), lower(app.missing), 'stable');
            [~, ~, index] = intersect(cchannels, lower({app.defaultlocs.chanlocs(:).labels}), 'stable');
            
            cchanlocs = app.defaultlocs.chanlocs(index);
            app.ShowLayoutUIFigure.HandleVisibility = 'callback';
            h = topoplot([],cchanlocs, 'style', 'blank', 'electrodes', 'ptslabels', 'headrad', 'rim');
            axis image;
            delete(app.UIAxes.Children(end-1:end));
            title(app.UIAxes, '');
            set(app.UIAxes.Children(1:length(cchanlocs)), 'FontSize', 12);
            app.ShowLayoutUIFigure.HandleVisibility = 'off';
        end
        
        function set_missing_channels(app)
            app.MissingChannelsField.Value = strjoin(app.missing, ', ');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, InputChannels, SelectedChannels)
            app.init(InputChannels, SelectedChannels);
            app.populate(); 
            app.handle = app.show_channel_layout();
            app.set_missing_channels();
        end

        % Close request function: ShowLayoutUIFigure
        function ShowLayoutUIFigureCloseRequest(app, event)
            delete(app)        
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShowLayoutUIFigure = uifigure('Visible', 'off');
            app.ShowLayoutUIFigure.Name = 'Show Layout';
            app.ShowLayoutUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShowLayoutUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.ShowLayoutUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.ShowLayoutUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TopographyPanel = uipanel(app.GridLayout2);
            app.TopographyPanel.Title = 'Topography:';
            app.UIAxes = uiaxes(app.TopographyPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.OkButton = uibutton(app.GridLayout4, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.MissingchannelsLabel = uilabel(app.GridLayout6);
            app.MissingchannelsLabel.Text = 'Missing channels:';
            app.MissingChannelsField = uieditfield(app.GridLayout6, 'text');
            app.MissingChannelsField.Editable = 'off';
            app.LayoutPanel = uipanel(app.GridLayout);
            app.LayoutPanel.Title = 'Layout:';
            app.GridLayout5 = uigridlayout(app.LayoutPanel);
            app.UITable = uitable(app.GridLayout5);
            app.UITable.ColumnName = {'#'; 'Input Channels'; 'Montage'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.ShowLayoutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChannelsLayout(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShowLayoutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowLayoutUIFigure)
        end
    end
end