classdef DataInfoOptions < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout8                  matlab.ui.container.GridLayout
        DatainfoPanel_2              matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        InputchannelsEditField       matlab.ui.control.EditField
        InputchannelsEditFieldLabel  matlab.ui.control.Label
        SampleRateHzEditField        matlab.ui.control.EditField
        SampleRateHzEditFieldLabel   matlab.ui.control.Label
        SubjectIdField               matlab.ui.control.EditField
        SubjectIdEditFieldLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        default_input_channels_labels = {};
        default_samplerate = ' ';
        default_subject_id = ' ';
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            options = comp.options;
        end
        
        function reset(comp)
            comp.set_subject_id(comp.default_subject_id);
            comp.set_samplerate(comp.default_samplerate);
            comp.set_input_channels(comp.default_input_channels_labels);
        end

        function set_subject_id(comp, subjectid)
            comp.SubjectIdField.Value = subjectid;
            comp.options.subject      = subjectid;
            comp.SubjectIdField.FontColor = 'k';
            comp.SubjectIdField.Tooltip   = '';          
        end

        function set_samplerate(comp, samplerate)
            if isempty(samplerate)
                comp.SampleRateHzEditField.Value = '';
                comp.options.samplerate = [];
            else
                comp.SampleRateHzEditField.Value = num2str(samplerate);
                comp.options.samplerate = samplerate;
            end
            comp.SampleRateHzEditField.FontColor = 'k';
            comp.SampleRateHzEditField.Tooltip   = '';
        end

        function set_input_channels(comp, inputchannels)
            if isempty(inputchannels)
                comp.InputchannelsEditField.Value = '';
                comp.options.labels = {};
            else
                comp.InputchannelsEditField.Value = num2str(length(inputchannels));
                comp.options.labels = inputchannels;
            end
            comp.InputchannelsEditField.FontColor = 'k';
            comp.InputchannelsEditField.Tooltip   = '';
        end

        function set_invalid_subject_id(comp)
            comp.SubjectIdField.Value     = '???';
            comp.options.subject          = [];
            comp.SubjectIdField.FontColor = 'r';
            comp.SubjectIdField.Tooltip   = 'Invalid subject id';  
        end

        function set_invalid_samplerate(comp)
            comp.SampleRateHzEditField.Value     = '0';
            comp.options.samplerate              = [];
            comp.SampleRateHzEditField.FontColor = 'r';
            comp.SampleRateHzEditField.Tooltip   = 'Invalid samplerate';
        end

        function set_invalid_input_channels(comp)
            comp.InputchannelsEditField.Value     = '0';
            comp.options.labels                   = {};
            comp.InputchannelsEditField.FontColor = 'r';
            comp.InputchannelsEditField.Tooltip   = 'Invalid input channels';
        end

        function set_field_format(~, obj, color, tooltip)
            obj.FontColor = color;
            obj.Tooltip   = tooltip;
        end

        function reset_field_format(~, obj)
            keyboard
            obj.FontColor = 'k';
            obj.Tooltip   = '';
        end
    end
    

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 197 177];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout8 = uigridlayout(comp);
            comp.DatainfoPanel_2 = uipanel(comp.GridLayout8);
            comp.DatainfoPanel_2.Title = 'Data info:';
            comp.GridLayout9 = uigridlayout(comp.DatainfoPanel_2);
            comp.SubjectIdEditFieldLabel = uilabel(comp.GridLayout9);
            comp.SubjectIdEditFieldLabel.Text = 'Subject Id:';
            comp.SubjectIdField = uieditfield(comp.GridLayout9, 'text');
            comp.SubjectIdField.Editable = 'off';
            comp.SampleRateHzEditFieldLabel = uilabel(comp.GridLayout9);
            comp.SampleRateHzEditFieldLabel.Text = 'Sample Rate [Hz]:';
            comp.SampleRateHzEditField = uieditfield(comp.GridLayout9, 'text');
            comp.SampleRateHzEditField.Editable = 'off';
            comp.InputchannelsEditFieldLabel = uilabel(comp.GridLayout9);
            comp.InputchannelsEditFieldLabel.Text = 'Input channels:';
            comp.InputchannelsEditField = uieditfield(comp.GridLayout9, 'text');
            comp.InputchannelsEditField.Editable = 'off';
        end
    end
end