classdef DecoderSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout    matlab.ui.container.GridLayout
        DecoderPanel  matlab.ui.container.Panel
        GridLayout22  matlab.ui.container.GridLayout
        GridLayout19  matlab.ui.container.GridLayout
        GridLayout20  matlab.ui.container.GridLayout
        LoadButton    matlab.ui.control.Button
        TypeDropDown  matlab.ui.control.DropDown
    end

    properties (Access = private)
        DecoderOptions
        default_type char = 'gaussian';
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            if isempty(comp.DecoderOptions) == false
                options = comp.DecoderOptions.retrieve();
            end

            options.type = comp.options.type;
            options = orderfields(options);
        end
        
        function reset(comp)
            comp.set_type(comp.default_type);

            if isempty(comp.DecoderOptions) == false
                comp.DecoderOptions.reset();
            end
        end
    end
    
    methods (Access = private)
        
        function init(comp)
            decoder_names       = {'Gaussian', 'LDA', 'QDA'};
            decoder_identifiers = {'gaussian', 'lda', 'qda'};
            
            comp.TypeDropDown.Items     = decoder_names;
            comp.TypeDropDown.ItemsData = decoder_identifiers;
            comp.set_type(comp.default_type);

            comp.import_component(comp.options.type);
            comp.reset();
        end

        function import_component(comp, type)

            % If already loaded, delete the component and empty the options
            if isempty(comp.DecoderOptions) == false
                comp.options = [];     
                delete(comp.DecoderOptions);
            end

            % Select and instantiate the new component
            switch(type)
                case 'gaussian'
                    comp.DecoderOptions = GaussianOptions(comp.GridLayout22);
                case 'lda'
                    comp.DecoderOptions = LDAOptions(comp.GridLayout22);
                case 'qda'
                    comp.DecoderOptions = QDAOptions(comp.GridLayout22);
                otherwise
                    error(['[DecoderSelector] Cannot find option class for this decoder: ' type]);
            end
            
            % Place the options in the correct position
            comp.DecoderOptions.Layout.Row    = 2;
            comp.DecoderOptions.Layout.Column = 1;
        end

        function set_type(comp, value)
            comp.TypeDropDown.Value = value;
            comp.options.type      = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.init();
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(comp, event)
    
            try
                comp.import_component(comp.TypeDropDown.Value);
                comp.set_type(comp.TypeDropDown.Value);
            catch
                warning(['[DecoderSelector] Cannot import the options for ''' comp.TypeDropDown.Value '''. Default decoder is loaded']);
                comp.import_component(comp.default_type);
                comp.set_type(comp.default_type);
            end

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 180];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.DecoderPanel = uipanel(comp.GridLayout);
            comp.DecoderPanel.Title = 'Decoder';
            comp.GridLayout22 = uigridlayout(comp.DecoderPanel);
            comp.GridLayout19 = uigridlayout(comp.GridLayout22);
            comp.TypeDropDown = uidropdown(comp.GridLayout19);
            comp.TypeDropDown.Items = {'Gaussian'};
            comp.TypeDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TypeDropDownValueChanged, true);
            comp.TypeDropDown.Value = 'Gaussian';
            comp.GridLayout20 = uigridlayout(comp.GridLayout19);
            comp.LoadButton = uibutton(comp.GridLayout20, 'push');
            comp.LoadButton.Enable = 'off';
            comp.LoadButton.Text = 'Load';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end