classdef FeaturesSelectionOptions < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout19              matlab.ui.container.GridLayout
        FeaturesselectionPanel_2  matlab.ui.container.Panel
        GridLayout20              matlab.ui.container.GridLayout
        NormalizationCheckBox     matlab.ui.control.CheckBox
        StabilityCheckBox         matlab.ui.control.CheckBox
        DropDown                  matlab.ui.control.DropDown
    end

    properties (Access = private)
        default_normalization logical = true;
        default_stability logical = true;
        default_type char = 'cva';
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            options = orderfields(comp.options);
        end
        
        function reset(comp)
            comp.set_type(comp.default_type);
            comp.set_stability(comp.default_stability);
            comp.set_normalization(comp.default_normalization);
        end
    end
    
    methods (Access = private)
        
        function init(comp)
            
            comp.DropDown.Items     = {'CVA', 'Fisher Score'};
            comp.DropDown.ItemsData = {'cva', 'fisher'}; 

            comp.reset();
        end

        function set_type(comp, value)
            comp.DropDown.Value = value;
            comp.options.type  = value;
        end

        function set_stability(comp, value)
            comp.StabilityCheckBox.Value = value;
            comp.options.stability      = value;
        end

        function set_normalization(comp, value)
            comp.NormalizationCheckBox.Value = value;
            comp.options.normalization      = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.init();
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            comp.set_type(comp.DropDown.Value);  
        end

        % Value changed function: StabilityCheckBox
        function StabilityCheckBoxValueChanged(comp, event)
            comp.set_stability(comp.StabilityCheckBox.Value);           
        end

        % Value changed function: NormalizationCheckBox
        function NormalizationCheckBoxValueChanged(comp, event)
            comp.set_normalization(comp.NormalizationCheckBox.Value);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 60];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout19 = uigridlayout(comp);
            comp.FeaturesselectionPanel_2 = uipanel(comp.GridLayout19);
            comp.FeaturesselectionPanel_2.Title = 'Features selection';
            comp.GridLayout20 = uigridlayout(comp.FeaturesselectionPanel_2);
            comp.DropDown = uidropdown(comp.GridLayout20);
            comp.DropDown.Items = {};
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
            comp.DropDown.Value = {};
            comp.StabilityCheckBox = uicheckbox(comp.GridLayout20);
            comp.StabilityCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @StabilityCheckBoxValueChanged, true);
            comp.StabilityCheckBox.Text = 'Stability';
            comp.NormalizationCheckBox = uicheckbox(comp.GridLayout20);
            comp.NormalizationCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @NormalizationCheckBoxValueChanged, true);
            comp.NormalizationCheckBox.Text = 'Normalization';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end