classdef FileSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        DatainfoPanel                 matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        SelectedChannelsField         matlab.ui.control.NumericEditField
        SelectedchannelsEditFieldLabel_3  matlab.ui.control.Label
        MontagePanel                  matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        LayoutLoad                    matlab.ui.control.Button
        LayoutShow                    matlab.ui.control.Button
        LayoutDropDown                matlab.ui.control.DropDown
        InputChannelField             matlab.ui.control.NumericEditField
        InputchannelsLabel            matlab.ui.control.Label
        SampleRateField               matlab.ui.control.NumericEditField
        SampleRateHzEditField_2Label  matlab.ui.control.Label
        SubjectIdField                matlab.ui.control.EditField
        SubjectIdEditFieldLabel       matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        SelectedfilesPanel            matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        SelectedBox                   matlab.ui.control.ListBox
        CurrentdirectoryPanel         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        BrowserBox                    matlab.ui.control.ListBox
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = private)
        options
    end
    
    
    methods (Access = private)
       
        %% Initialize the GUI
        function init(comp)
            classname = [class(comp) '.mlapp'];
            classpath = which(classname);
            rootpath = fileparts(classpath);
            
            configpath = fullfile(rootpath, 'config', 'layouts');
            montages = neurodata_get_montages(configpath);
            
            comp.LayoutDropDown.Items = {'Select...', montages(:).name};
            comp.LayoutDropDown.ItemsData = cat(1, struct('name', '', 'index', [], 'labels', {''}), montages);
            comp.disable_layout_change();
        end

        %% Populate the browser
        function populate(comp, path)
     
            entries = dir(path);
            names = {entries.name}; 
            index  = 1:length(entries);
            rmindx = find(contains('.', names));
            entries = entries(setdiff(index, rmindx));
            comp.BrowserBox.Items = {entries.name};
            comp.BrowserBox.ItemsData = entries;
            comp.BrowserBox.Value = {};
            
        end
        
        %% Enable/Disable layout button
        function enable_layout_change(comp)
            comp.LayoutDropDown.Enable = 'on';
            comp.LayoutShow.Enable     = 'on';
            comp.LayoutLoad.Enable     = 'on';          
        end
        function disable_layout_change(comp)
            comp.LayoutDropDown.Enable = 'off';
            comp.LayoutShow.Enable     = 'off';
            comp.LayoutLoad.Enable     = 'off';            
        end
        
        %% Add file to the selected box
        function addfile(comp, filepath)
            [~, filename, ext] = fileparts(filepath);

            if(strcmpi(ext, '.gdf') == false)
                return
            end

            if any(strcmpi(comp.SelectedBox.Items, [filename ext])) == false
                comp.SelectedBox.Items{end+1} = [filename ext];
                comp.SelectedBox.ItemsData{end+1} = comp.get_file_info(filepath);
                notify(comp, 'ValueChanged');
            end
            
        end
        
        %% Remove file from the selected box
        function rmfile(comp, fileid)
            index  = 1:length(comp.SelectedBox.Items);
            rmindx = setdiff(index, fileid);
            comp.SelectedBox.Items = comp.SelectedBox.Items(rmindx);
            comp.SelectedBox.ItemsData = comp.SelectedBox.ItemsData(rmindx);
            notify(comp, 'ValueChanged');
        end
        
        %% Set field value and format
        function set_field(~, obj, value, color, tooltip)
            obj.Value     = value;
            obj.FontColor = color;
            obj.Tooltip   = tooltip;
        end

        %% Update samplerate
        function samplerate = update_samplerate(comp)
 
            list = [];
            for fId = 1:length(comp.SelectedBox.ItemsData)
                list = cat(1, list, comp.SelectedBox.ItemsData{fId}.samplerate);
            end
            
            if (isempty(list) == true)
                comp.set_field(comp.SampleRateField, 0, 'k', '');
            elseif (length(unique(list)) == 1)
                comp.set_field(comp.SampleRateField, unique(list), 'k', '');
            else
                comp.set_field(comp.SampleRateField, 0, 'r', 'Selected files have different sampling rate');
            end

            samplerate = comp.SampleRateField.Value;
        end

        %% Update input channels
        function labels = update_input_channels(comp)
          
            list = {};
            labels = {};
            for fId = 1:length(comp.SelectedBox.ItemsData)
                list = cat(1, list, {comp.SelectedBox.ItemsData{fId}.labels});
            end
            
            comp.enable_layout_change();

            if (isempty(list) == true)
                comp.set_field(comp.InputChannelField, 0, 'k', '');
                comp.disable_layout_change();
            elseif length(list) == 1
                comp.set_field(comp.InputChannelField, length(list{1}), 'k', '');
                labels = list{1};
            elseif (isequal(list{:}) == true)
                comp.set_field(comp.InputChannelField, length(list{1}), 'k', '');
                labels = list{1};
            else
                comp.set_field(comp.InputChannelField, 0, 'r', 'Selected files have different channels');
                comp.disable_layout_change();
            end

        end

        %% Update subject id
        function subjectid = update_subject_id(comp)    

            list = {};
            for fId = 1:length(comp.SelectedBox.ItemsData)
                list = cat(1, list, {comp.SelectedBox.ItemsData{fId}.subject});
            end

            if (isempty(list) == true)
                comp.set_field(comp.SubjectIdField, '', 'k', '');
            elseif length(list) == 1
                comp.set_field(comp.SubjectIdField, list{1}, 'k', '');
            elseif (isequal(list{:}) == true)
                comp.set_field(comp.SubjectIdField, list{1}, 'k', '');
            else
                comp.set_field(comp.SubjectIdField, '???', 'r', 'Selected files have different subejct');
            end

            subjectid = comp.SubjectIdField.Value;
        end

        %% Update selected channels
        function labels = update_selected_channels(comp)
            value = comp.LayoutDropDown.Value;
            labels = [];
            if isempty(comp.SelectedBox.ItemsData) == true
                comp.set_field(comp.SelectedChannelsField, 0, 'k', '');
                comp.LayoutShow.Enable = false;
            elseif(isempty(value.name) == false)
                if( length(value.labels) > length(comp.options.channels.input) )
                    comp.set_field(comp.SelectedChannelsField, 0, 'r', 'Selected montage does not match');
                    comp.LayoutShow.Enable = false;
                else
                    comp.set_field(comp.SelectedChannelsField, length(value.labels), 'k', '');
                    labels = value.labels;
                    comp.LayoutShow.Enable = true;
                end
               
            end
        end
        
        %% Get file info
        function info = get_file_info(~, filepath)
            h = neuroio_import_header(filepath);
            [~, filename] = fileparts(filepath);
            info.samplerate = h.SampleRate;
            info.labels     = h.Label;
            info.labels(contains(lower(info.labels), lower({'trigger', 'status'}))) = [];
            info.subject    = char(regexp(filename, '(\w+).', 'tokens', 'once'));
         
        end
        
    end
    
    methods (Access = public)
        
        %% Reset component
        function reset(comp)
            comp.SelectedBox.Items = {};
            comp.SelectedBox.ItemsData = {};

            comp.disable_layout_change();
            notify(comp, 'ValueChanged');
        end
        
        %% Get selected files
        function [files, options] = getfiles(comp)
            files = comp.SelectedBox.ItemsData; 
            options = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
            % Init gui components
            comp.init();

            % Browser box
            populate(comp, pwd);

        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(comp, event)

            % update selected channels
            comp.options.channels.selected = comp.update_selected_channels();
        end

        % Button pushed function: LayoutShow
        function LayoutShowButtonPushed(comp, event)
            if(isempty(comp.LayoutDropDown.Value.name) == false)
                ChannelsLayout(comp.options.channels.input, comp.options.channels.selected);
            end
        end

        % Clicked callback: BrowserBox
        function BrowserBoxClicked(comp, event)
            item = event.InteractionInformation.Item;
            selItem = comp.BrowserBox.ItemsData(item);

            pathItem = fullfile(selItem.folder, selItem.name);

            if(selItem.isdir)
                populate(comp, pathItem);
                return
            end
            
            comp.addfile(pathItem);

            % update subjectid
            comp.options.subject = comp.update_subject_id();

            % update samplerate
            comp.options.samplerate = comp.update_samplerate();

            % check input channels
            comp.options.channels.input = comp.update_input_channels();

            % update selected channels
            comp.options.channels.selected = comp.update_selected_channels();

            
        end

        % Clicked callback: SelectedBox
        function SelectedBoxClicked(comp, event)
            item = event.InteractionInformation.Item;
            comp.rmfile(item);

            % update subjectid
            comp.options.subject = comp.update_subject_id();

            % update samplerate
            comp.options.samplerate = comp.update_samplerate();

            % check input channels
            comp.options.channels.input = comp.update_input_channels();

            % update selected channels
            comp.options.channels.selected = comp.update_selected_channels();
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1065 369];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.CurrentdirectoryPanel = uipanel(comp.GridLayout2);
            comp.CurrentdirectoryPanel.Title = 'Current directory:';
            comp.GridLayout3 = uigridlayout(comp.CurrentdirectoryPanel);
            comp.BrowserBox = uilistbox(comp.GridLayout3);
            comp.BrowserBox.Items = {};
            comp.BrowserBox.Multiselect = 'on';
            comp.BrowserBox.Tag = 'browserbox';
            comp.BrowserBox.ClickedFcn = matlab.apps.createCallbackFcn(comp, @BrowserBoxClicked, true);
            comp.BrowserBox.Value = {};
            comp.SelectedfilesPanel = uipanel(comp.GridLayout2);
            comp.SelectedfilesPanel.Title = 'Selected file(s):';
            comp.GridLayout4 = uigridlayout(comp.SelectedfilesPanel);
            comp.SelectedBox = uilistbox(comp.GridLayout4);
            comp.SelectedBox.Items = {};
            comp.SelectedBox.ClickedFcn = matlab.apps.createCallbackFcn(comp, @SelectedBoxClicked, true);
            comp.SelectedBox.Value = {};
            comp.DatainfoPanel = uipanel(comp.GridLayout);
            comp.DatainfoPanel.Title = 'Data info:';
            comp.GridLayout5 = uigridlayout(comp.DatainfoPanel);
            comp.SubjectIdEditFieldLabel = uilabel(comp.GridLayout5);
            comp.SubjectIdEditFieldLabel.Text = 'Subject Id:';
            comp.SubjectIdField = uieditfield(comp.GridLayout5, 'text');
            comp.SubjectIdField.Editable = 'off';
            comp.SampleRateHzEditField_2Label = uilabel(comp.GridLayout5);
            comp.SampleRateHzEditField_2Label.Text = 'Sample Rate [Hz]:';
            comp.SampleRateField = uieditfield(comp.GridLayout5, 'numeric');
            comp.SampleRateField.Limits = [0 Inf];
            comp.SampleRateField.Editable = 'off';
            comp.InputchannelsLabel = uilabel(comp.GridLayout5);
            comp.InputchannelsLabel.Text = 'Input channels:';
            comp.InputChannelField = uieditfield(comp.GridLayout5, 'numeric');
            comp.InputChannelField.Limits = [0 Inf];
            comp.InputChannelField.Editable = 'off';
            comp.MontagePanel = uipanel(comp.GridLayout5);
            comp.MontagePanel.Title = 'Montage:';
            comp.GridLayout6 = uigridlayout(comp.MontagePanel);
            comp.LayoutDropDown = uidropdown(comp.GridLayout6);
            comp.LayoutDropDown.Items = {};
            comp.LayoutDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LayoutDropDownValueChanged, true);
            comp.LayoutDropDown.Enable = 'off';
            comp.LayoutDropDown.Value = {};
            comp.LayoutShow = uibutton(comp.GridLayout6, 'push');
            comp.LayoutShow.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LayoutShowButtonPushed, true);
            comp.LayoutShow.Enable = 'off';
            comp.LayoutShow.Text = 'Show';
            comp.LayoutLoad = uibutton(comp.GridLayout6, 'push');
            comp.LayoutLoad.Enable = 'off';
            comp.LayoutLoad.Text = 'Load';
            comp.SelectedchannelsEditFieldLabel_3 = uilabel(comp.GridLayout5);
            comp.SelectedchannelsEditFieldLabel_3.Text = 'Selected channels:';
            comp.SelectedChannelsField = uieditfield(comp.GridLayout5, 'numeric');
            comp.SelectedChannelsField.Limits = [0 Inf];
            comp.SelectedChannelsField.Editable = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end