classdef GaussianOptions < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        GridLayout21              matlab.ui.container.GridLayout
        normalizationCheckBox     matlab.ui.control.CheckBox
        sharedcovarianceCheckBox  matlab.ui.control.CheckBox
        thresholdEditField        matlab.ui.control.NumericEditField
        thresholdEditFieldLabel   matlab.ui.control.Label
        SOMunitsEditField         matlab.ui.control.EditField
        SOMunitsEditFieldLabel    matlab.ui.control.Label
        micovEditField            matlab.ui.control.NumericEditField
        micovEditFieldLabel       matlab.ui.control.Label
        mimeanEditField           matlab.ui.control.NumericEditField
        mimeanEditFieldLabel      matlab.ui.control.Label
        epochsEditField           matlab.ui.control.NumericEditField
        epochsEditFieldLabel      matlab.ui.control.Label
    end

    properties (Access = private)
        default_micovariance double = 0.00001;
        default_mimean double = 0.0001;
        default_normalization logical = true;
        default_shared_covariance logical = true;
        default_som_units double = [2 2];
        default_training_epochs double = 20;
        default_training_threshold double = 0.7;
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            options = orderfields(comp.options);
        end
        
        function reset(comp)
            comp.set_shared_covariance(comp.default_shared_covariance);
            comp.set_normalization(comp.default_normalization);
            comp.set_som_units(comp.default_som_units);
            comp.set_training_epochs(comp.default_training_epochs);
            comp.set_training_threshold(comp.default_training_threshold);
            comp.set_mimean(comp.default_mimean);
            comp.set_micovariance(comp.default_micovariance);
        end
    end


    methods (Access = private)
        
        function init(comp)
            comp.reset();
        end

        function set_shared_covariance(comp, value)
            comp.sharedcovarianceCheckBox.Value = value;
            comp.options.shared_covariance     = value;
        end

        function set_normalization(comp, value)
            comp.normalizationCheckBox.Value = value;
            comp.options.normalization      = value;
        end

        function set_som_units(comp, value)
            comp.SOMunitsEditField.Value = num2str(value);
            comp.options.som_units      = value;
        end

        function set_training_epochs(comp, value)
            comp.epochsEditField.Value = value;
            comp.options.epochs       = value;
        end

        function set_training_threshold(comp, value)
            comp.thresholdEditField.Value = value;
            comp.options.threshold       = value;
        end

        function set_mimean(comp, value)
            comp.mimeanEditField.Value = value;
            comp.options.mimean       = value;
        end

        function set_micovariance(comp, value)
            comp.micovEditField.Value  = value;
            comp.options.micovariance = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.init();
        end

        % Value changed function: SOMunitsEditField
        function SOMunitsEditFieldValueChanged(comp, event)
            comp.set_som_units(str2double(cellstr(strsplit(comp.SOMunitsEditField.Value))));
        end

        % Value changed function: mimeanEditField
        function mimeanEditFieldValueChanged(comp, event)
            comp.set_mimean(comp.mimeanEditField.Value);
        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(comp, event)
            comp.set_training_threshold(comp.thresholdEditField.Value);
        end

        % Value changed function: epochsEditField
        function epochsEditFieldValueChanged(comp, event)
            comp.set_training_epochs(comp.epochsEditField.Value);
        end

        % Value changed function: micovEditField
        function micovEditFieldValueChanged(comp, event)
            comp.set_micovariance(comp.micovEditField.Value);
        end

        % Value changed function: sharedcovarianceCheckBox
        function sharedcovarianceCheckBoxValueChanged(comp, event)
            comp.set_shared_covariance(comp.sharedcovarianceCheckBox.Value);
        end

        % Value changed function: normalizationCheckBox
        function normalizationCheckBoxValueChanged(comp, event)
            comp.set_normalization(comp.normalizationCheckBox.Value);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 120];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout21 = uigridlayout(comp.GridLayout);
            comp.epochsEditFieldLabel = uilabel(comp.GridLayout21);
            comp.epochsEditFieldLabel.Text = 'epochs:';
            comp.epochsEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.epochsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @epochsEditFieldValueChanged, true);
            comp.mimeanEditFieldLabel = uilabel(comp.GridLayout21);
            comp.mimeanEditFieldLabel.Text = 'mimean:';
            comp.mimeanEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.mimeanEditField.ValueDisplayFormat = '%11.5f';
            comp.mimeanEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @mimeanEditFieldValueChanged, true);
            comp.micovEditFieldLabel = uilabel(comp.GridLayout21);
            comp.micovEditFieldLabel.Text = 'micov:';
            comp.micovEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.micovEditField.ValueDisplayFormat = '%11.5f';
            comp.micovEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @micovEditFieldValueChanged, true);
            comp.SOMunitsEditFieldLabel = uilabel(comp.GridLayout21);
            comp.SOMunitsEditFieldLabel.Text = 'SOM units:';
            comp.SOMunitsEditField = uieditfield(comp.GridLayout21, 'text');
            comp.SOMunitsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SOMunitsEditFieldValueChanged, true);
            comp.thresholdEditFieldLabel = uilabel(comp.GridLayout21);
            comp.thresholdEditFieldLabel.Text = 'threshold:';
            comp.thresholdEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.thresholdEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @thresholdEditFieldValueChanged, true);
            comp.sharedcovarianceCheckBox = uicheckbox(comp.GridLayout21);
            comp.sharedcovarianceCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sharedcovarianceCheckBoxValueChanged, true);
            comp.sharedcovarianceCheckBox.Text = 'shared covariance';
            comp.normalizationCheckBox = uicheckbox(comp.GridLayout21);
            comp.normalizationCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @normalizationCheckBoxValueChanged, true);
            comp.normalizationCheckBox.Text = 'normalization';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end