classdef LDAOptions < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        GridLayout2           matlab.ui.container.GridLayout
        loss2EditField        matlab.ui.control.EditField
        loss1EditField        matlab.ui.control.EditField
        lossEditFieldLabel    matlab.ui.control.Label
        priorsEditField       matlab.ui.control.EditField
        priorsEditFieldLabel  matlab.ui.control.Label
        rejectCheckBox        matlab.ui.control.CheckBox
        shrinkCheckBox        matlab.ui.control.CheckBox
    end

    properties (Access = private)
        default_loss double = [0 1; 0 1];
        default_priors double = [0.5 0.5];
        default_reject logical = true;
        default_shrink logical = true;
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            options = orderfields(comp.options);
        end
        
        function reset(comp)
            comp.set_loss_all(comp.default_loss);
            comp.set_priors(comp.default_priors);
            comp.set_reject(comp.default_reject);
            comp.set_shrink(comp.default_shrink);
        end
    end
    
    methods (Access = private)
        
         function init(comp)
            comp.reset();
         end

         function set_loss_all(comp, value)
            comp.loss1EditField.Value = num2str(value(1, :), '%4.2f  %4.2f');
            comp.loss2EditField.Value = num2str(value(2, :), '%4.2f  %4.2f');
            comp.options.loss         = value;
         end

        function set_loss_class1(comp, value)
            comp.loss1EditField.Value = num2str(value, '%4.2f  %4.2f');
            comp.options.loss        = [value; str2double(cellstr(strsplit(comp.loss2EditField.Value)))];
        end

        function set_loss_class2(comp, value)
            comp.loss2EditField.Value = num2str(value, '%4.2f  %4.2f');
            comp.options.loss        = [str2double(cellstr(strsplit(comp.loss1EditField.Value))); value];
        end

        function set_priors(comp, value)
            comp.priorsEditField.Value = num2str(value, '%4.2f  %4.2f');
            comp.options.priors        = value;
        end

        function set_reject(comp, value)
            comp.rejectCheckBox.Value = value;
            comp.options.reject       = value;
        end

        function set_shrink(comp, value)
            comp.shrinkCheckBox.Value = value;
            comp.options.shrink       = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.init();
        end

        % Value changed function: shrinkCheckBox
        function shrinkCheckBoxValueChanged(comp, event)
            comp.set_shrink(comp.shrinkCheckBox.Value);
        end

        % Value changed function: rejectCheckBox
        function rejectCheckBoxValueChanged(comp, event)
            comp.set_reject(comp.rejectCheckBox.Value);
        end

        % Value changed function: priorsEditField
        function priorsEditFieldValueChanged(comp, event)
            comp.set_priors(str2double(cellstr(strsplit(comp.priorsEditField.Value))));
        end

        % Value changed function: loss1EditField
        function loss1EditFieldValueChanged(comp, event)
            comp.set_loss_class1(str2double(cellstr(strsplit(comp.loss1EditField.Value))));
        end

        % Value changed function: loss2EditField
        function loss2EditFieldValueChanged(comp, event)
            comp.set_loss_class2(str2double(cellstr(strsplit(comp.loss2EditField.Value))));
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 120];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.shrinkCheckBox = uicheckbox(comp.GridLayout2);
            comp.shrinkCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @shrinkCheckBoxValueChanged, true);
            comp.shrinkCheckBox.Text = 'shrink';
            comp.rejectCheckBox = uicheckbox(comp.GridLayout2);
            comp.rejectCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @rejectCheckBoxValueChanged, true);
            comp.rejectCheckBox.Text = 'reject';
            comp.priorsEditFieldLabel = uilabel(comp.GridLayout2);
            comp.priorsEditFieldLabel.Text = 'priors:';
            comp.priorsEditField = uieditfield(comp.GridLayout2, 'text');
            comp.priorsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @priorsEditFieldValueChanged, true);
            comp.lossEditFieldLabel = uilabel(comp.GridLayout2);
            comp.lossEditFieldLabel.Text = 'loss:';
            comp.loss1EditField = uieditfield(comp.GridLayout2, 'text');
            comp.loss1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @loss1EditFieldValueChanged, true);
            comp.loss2EditField = uieditfield(comp.GridLayout2, 'text');
            comp.loss2EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @loss2EditFieldValueChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end