classdef MontageSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout8             matlab.ui.container.GridLayout
        MontagePanel            matlab.ui.container.Panel
        GridLayout9             matlab.ui.container.GridLayout
        GridLayout12            matlab.ui.container.GridLayout
        LayoutDropDown          matlab.ui.control.DropDown
        GridLayout11            matlab.ui.container.GridLayout
        LayoutShow              matlab.ui.control.Button
        LayoutLoad              matlab.ui.control.Button
        GridLayout10            matlab.ui.container.GridLayout
        channelsEditField       matlab.ui.control.EditField
        channelsEditFieldLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        MontageSelectorShowClicked
        MontageSelectorValueChanged
    end

    properties (Access = private)
        options
    end
    
    
    methods (Access = private)
        
        function init(comp)
            montages = comp.get_montage_list();
            comp.LayoutDropDown.Items = {'Select...', montages(:).name};
            comp.LayoutDropDown.ItemsData = cat(1, struct('name', '', 'index', [], 'labels', {''}), montages);
            comp.options.labels = {};
            comp.enable_montage_selector();
            comp.disable_montage_load();
            comp.disable_montage_show();
        end


        function montages = get_montage_list(comp)
            classname = [class(comp) '.mlapp'];
            classpath = which(classname);
            rootpath = fileparts(classpath);
            
            configpath = fullfile(rootpath, 'config', 'layouts');
            montages = neurodata_get_montages(configpath);
        end


        function enable_montage_selector(comp)
            comp.LayoutDropDown.Enable = 'on';
        end


        function enable_montage_load(comp)
            comp.LayoutLoad.Enable     = 'on';   
        end

        function disable_montage_selector(comp)
            comp.LayoutDropDown.Enable = 'off';
        end


        function disable_montage_load(comp)
            comp.LayoutLoad.Enable     = 'off';   
        end
    end
    
    methods (Access = public)
        
        function reset(comp)
            comp.LayoutDropDown.Value    = comp.LayoutDropDown.ItemsData(1);
            comp.options.labels          = {};
            comp.channelsEditField.Value = '';
            comp.enable_montage_selector();
            comp.disable_montage_load();
            comp.disable_montage_show();
        end

        function options = retrieve(comp)
            options = comp.options;
        end

        function set_montage_channels(comp, value)
            comp.options.labels = value;
            comp.channelsEditField.Value     = num2str(length(comp.options.labels));
            comp.channelsEditField.FontColor = 'k';
            comp.channelsEditField.Tooltip   = '';  
        end

        function set_invalid_montage_channels(comp)
            comp.channelsEditField.FontColor = 'r';
            comp.channelsEditField.Tooltip   = 'Invalid montage';  
        end

        function disable_montage_show(comp)
            comp.LayoutShow.Enable     = 'off';       
        end
        
        function enable_montage_show(comp)
            comp.LayoutShow.Enable     = 'on';       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.init();
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(comp, event)
            value = comp.LayoutDropDown.Value;

            if(isempty(value.name) == false)
                
                comp.set_montage_channels(value.labels);
                comp.enable_montage_show();
            else
                comp.options.labels = {};
                comp.channelsEditField.Value = '';
                comp.disable_montage_show();
            end
            notify(comp, 'MontageSelectorValueChanged');
        end

        % Button pushed function: LayoutShow
        function LayoutShowButtonPushed(comp, event)
            notify(comp, 'MontageSelectorShowClicked');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 202 170];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout8 = uigridlayout(comp);
            comp.MontagePanel = uipanel(comp.GridLayout8);
            comp.MontagePanel.Title = 'Montage:';
            comp.GridLayout9 = uigridlayout(comp.MontagePanel);
            comp.GridLayout10 = uigridlayout(comp.GridLayout9);
            comp.channelsEditFieldLabel = uilabel(comp.GridLayout10);
            comp.channelsEditFieldLabel.Text = 'channels:';
            comp.channelsEditField = uieditfield(comp.GridLayout10, 'text');
            comp.GridLayout11 = uigridlayout(comp.GridLayout9);
            comp.LayoutLoad = uibutton(comp.GridLayout11, 'push');
            comp.LayoutLoad.Enable = 'off';
            comp.LayoutLoad.Text = 'Load';
            comp.LayoutShow = uibutton(comp.GridLayout11, 'push');
            comp.LayoutShow.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LayoutShowButtonPushed, true);
            comp.LayoutShow.Enable = 'off';
            comp.LayoutShow.Text = 'Show';
            comp.GridLayout12 = uigridlayout(comp.GridLayout9);
            comp.LayoutDropDown = uidropdown(comp.GridLayout12);
            comp.LayoutDropDown.Items = {};
            comp.LayoutDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LayoutDropDownValueChanged, true);
            comp.LayoutDropDown.Enable = 'off';
            comp.LayoutDropDown.Value = {};
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end