classdef PreprocessingOptions < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        PreprocessingPanel  matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        LaplacianCheckBox   matlab.ui.control.CheckBox
        CARCheckBox         matlab.ui.control.CheckBox
        DCremovalCheckBox   matlab.ui.control.CheckBox
    end

    properties (Access = private)
        default_car_value logical = false;
        default_dc_value logical = true;
        default_laplacian_value logical = true;
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            options = orderfields(comp.options);
        end
        
        function reset(comp)
            % Default values
            comp.set_dc(comp.default_dc_value);
            comp.set_car(comp.default_car_value);
            comp.set_laplacian(comp.default_laplacian_value);
        end

        function set_dc(comp, value)
            comp.DCremovalCheckBox.Value = value;
            comp.options.dc.enable = value;
        end

        function set_car(comp, value)
            comp.CARCheckBox.Value   = value;
            comp.options.car.enable = value;
        end

        function set_laplacian(comp, value)
            comp.LaplacianCheckBox.Value   = value;
            comp.options.laplacian.enable = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
      
            % Assign default values
            comp.reset();
        end

        % Value changed function: DCremovalCheckBox
        function DCremovalCheckBoxValueChanged(comp, event)
            comp.set_dc(comp.DCremovalCheckBox.Value);
        end

        % Value changed function: CARCheckBox
        function CARCheckBoxValueChanged(comp, event)
            comp.set_car(comp.CARCheckBox.Value);
        end

        % Value changed function: LaplacianCheckBox
        function LaplacianCheckBoxValueChanged(comp, event)
            comp.set_laplacian(comp.LaplacianCheckBox.Value);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
   
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 60];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.PreprocessingPanel = uipanel(comp.GridLayout);
            comp.PreprocessingPanel.Title = 'Pre-processing';
            comp.GridLayout3 = uigridlayout(comp.PreprocessingPanel);
            comp.DCremovalCheckBox = uicheckbox(comp.GridLayout3);
            comp.DCremovalCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DCremovalCheckBoxValueChanged, true);
            comp.DCremovalCheckBox.Text = 'DC removal';
            comp.CARCheckBox = uicheckbox(comp.GridLayout3);
            comp.CARCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CARCheckBoxValueChanged, true);
            comp.CARCheckBox.Text = 'CAR';
            comp.LaplacianCheckBox = uicheckbox(comp.GridLayout3);
            comp.LaplacianCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @LaplacianCheckBoxValueChanged, true);
            comp.LaplacianCheckBox.Text = 'Laplacian';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end