classdef SpectrogramOptions < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout5                  matlab.ui.container.GridLayout
        SpectrogramPanel             matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        AverageField                 matlab.ui.control.NumericEditField
        averagesEditFieldLabel       matlab.ui.control.Label
        InnerOverlapField            matlab.ui.control.NumericEditField
        inneroverlapsLabel           matlab.ui.control.Label
        WindowShiftField             matlab.ui.control.NumericEditField
        windowshiftsLabel            matlab.ui.control.Label
        WindowLengthField            matlab.ui.control.NumericEditField
        windowlengthsEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        default_average double = 1;
        default_inner_overlap double = 0.5;
        default_window_length double = 0.5;
        default_window_shift double = 0.0625;
        options
    end
    
    
    methods (Access = public)
        
        function options = retrieve(comp)
            options = orderfields(comp.options);
        end
        
        function reset(comp)
            % Default values
            comp.set_window_length(comp.default_window_length);
            comp.set_window_shift(comp.default_window_shift);
            comp.set_inner_overlap(comp.default_inner_overlap);
            comp.set_average(comp.default_average);
        end

        function set_window_length(comp, value)
            comp.WindowLengthField.Value = value;
            comp.options.window_length  = value; 
        end

        function set_window_shift(comp, value)
            comp.WindowShiftField.Value = value;
            comp.options.window_shift  = value;
        end

        function set_inner_overlap(comp, value)
            comp.InnerOverlapField.Value = value;
            comp.options.inner_overlap  = value;
        end

        function set_average(comp, value)
            comp.AverageField.Value = value;
            comp.options.average   = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            
            % Defaul values
            comp.reset();
        end

        % Value changed function: WindowLengthField
        function WindowLengthFieldValueChanged(comp, event)
            comp.set_window_length(comp.WindowLengthField.Value);          
        end

        % Value changed function: InnerOverlapField
        function InnerOverlapFieldValueChanged(comp, event)
            comp.set_inner_overlap(comp.InnerOverlapField.Value);
        end

        % Value changed function: AverageField
        function AverageFieldValueChanged(comp, event)
            comp.set_average(comp.AverageField.Value);
        end

        % Value changed function: WindowShiftField
        function WindowShiftFieldValueChanged(comp, event)
            comp.set_window_shift(comp.WindowShiftField.Value);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 120];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout5 = uigridlayout(comp);
            comp.SpectrogramPanel = uipanel(comp.GridLayout5);
            comp.SpectrogramPanel.Title = 'Spectrogram';
            comp.GridLayout4 = uigridlayout(comp.SpectrogramPanel);
            comp.windowlengthsEditFieldLabel = uilabel(comp.GridLayout4);
            comp.windowlengthsEditFieldLabel.Text = 'window length [s]:';
            comp.WindowLengthField = uieditfield(comp.GridLayout4, 'numeric');
            comp.WindowLengthField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @WindowLengthFieldValueChanged, true);
            comp.windowshiftsLabel = uilabel(comp.GridLayout4);
            comp.windowshiftsLabel.Text = 'window shift [s]:';
            comp.WindowShiftField = uieditfield(comp.GridLayout4, 'numeric');
            comp.WindowShiftField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @WindowShiftFieldValueChanged, true);
            comp.inneroverlapsLabel = uilabel(comp.GridLayout4);
            comp.inneroverlapsLabel.Text = 'inner overlap [s]:';
            comp.InnerOverlapField = uieditfield(comp.GridLayout4, 'numeric');
            comp.InnerOverlapField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @InnerOverlapFieldValueChanged, true);
            comp.averagesEditFieldLabel = uilabel(comp.GridLayout4);
            comp.averagesEditFieldLabel.Text = 'average [s]:';
            comp.AverageField = uieditfield(comp.GridLayout4, 'numeric');
            comp.AverageField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @AverageFieldValueChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end