classdef TaskSelector < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout19            matlab.ui.container.GridLayout
        TasksPanel              matlab.ui.container.Panel
        GridLayout18            matlab.ui.container.GridLayout
        CustomEvent2Field       matlab.ui.control.NumericEditField
        CustomEvent1Field       matlab.ui.control.NumericEditField
        CustomIdentifierField   matlab.ui.control.EditField
        CustomNameField         matlab.ui.control.EditField
        TasksetIdentifierField  matlab.ui.control.EditField
        TasksetEvent2Field      matlab.ui.control.NumericEditField
        TasksetEvent1Field      matlab.ui.control.NumericEditField
        EventLabel2             matlab.ui.control.Label
        EventLabel1             matlab.ui.control.Label
        IdentifierLabel         matlab.ui.control.Label
        TasksetDropDown         matlab.ui.control.DropDown
        TasksetLabel            matlab.ui.control.Label
    end

    properties (Access = private)
        default_taskset char = 'bhbf';
        settings
        tasksets
    end
    
    
    methods (Access = private)
        
        function init(comp)
            comp.tasksets.name       = {'both hands vs. both feet', 'right hand vs. left hand', 'both hands vs. rest', 'both feet vs. rest', 'right hand vs. rest', 'left hand vs. rest', 'Custom...'};
            comp.tasksets.identifier = {'bhbf', 'rhlh', 'bhrst', 'bfrst', 'rhrst', 'lhrst', 'custom'};
            comp.tasksets.events     = {[773 771], [770 769], [773 783], [771 783], [770 783], [769 783], []}; 
    
            comp.TasksetDropDown.Items     = comp.tasksets.name;
            comp.TasksetDropDown.ItemsData = comp.tasksets.identifier;

            % Reset to default value
            comp.reset();        
        end


        function [name, identifier, events] = get_current_taskset(comp, value)
            index      = find(ismember(comp.TasksetDropDown.ItemsData, value));
            name       = comp.tasksets.name{index};
            identifier = comp.tasksets.identifier{index}; 
            events     = comp.tasksets.events{index};
        end

        function [name, identifier, events] = get_custom_taskset(comp)
            name       = comp.CustomNameField.Value;
            identifier = comp.CustomIdentifierField.Value; 
            events     = [comp.CustomEvent1Field.Value comp.CustomEvent2Field.Value];
        end

        function update_taskset_fields(comp, identifier, events)
            comp.TasksetIdentifierField.Value = identifier;
            comp.TasksetEvent1Field.Value     = events(1);
            comp.TasksetEvent2Field.Value     = events(2);
        end

        function enable_custom_taskset(comp)
            comp.CustomNameField.Enable       = true;
            comp.CustomIdentifierField.Enable = true;
            comp.CustomEvent1Field.Enable     = true;
            comp.CustomEvent2Field.Enable     = true;
        end

        function disable_custom_taskset(comp)
            comp.CustomNameField.Enable       = false;
            comp.CustomIdentifierField.Enable = false;
            comp.CustomEvent1Field.Enable     = false;
            comp.CustomEvent2Field.Enable     = false;
        end

        function enable_default_taskset(comp)
            comp.TasksetIdentifierField.Enable = true;
            comp.TasksetEvent1Field.Enable     = true;
            comp.TasksetEvent2Field.Enable     = true;
        end

        function disable_default_taskset(comp)
            comp.TasksetIdentifierField.Enable = false;
            comp.TasksetEvent1Field.Enable     = false;
            comp.TasksetEvent2Field.Enable     = false;
        end

        function update_settings(comp, name, identifier, events)
            comp.settings.name       = name;
            comp.settings.identifier = identifier;
            comp.settings.events     = events;
        end

    end
    
    methods (Access = public)
        
        function settings = retrieve(comp)
            settings = orderfields(comp.settings);
        end

        function reset(comp)

            [name, identifier, events] = comp.get_current_taskset(comp.default_taskset);
            comp.update_taskset_fields(identifier, events);
            comp.update_settings(name, identifier, events);

            comp.CustomNameField.Value       = '';
            comp.CustomIdentifierField.Value = '';
            comp.CustomEvent1Field.Value     = 0;
            comp.CustomEvent2Field.Value     = 0;

             % Enable taskset and disable custom
            comp.enable_default_taskset();
            comp.disable_custom_taskset();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.init();            
        end

        % Value changed function: TasksetDropDown
        function TasksetDropDownValueChanged(comp, event)
            if (strcmpi(comp.TasksetDropDown.Value, 'custom')) == false
                % Get current taskset
                [name, identifier, events] = comp.get_current_taskset(comp.TasksetDropDown.Value);

                % Update fields and settings
                comp.update_taskset_fields(identifier, events);
                comp.update_settings(name, identifier, events);
                
                % Enable taskset and disable custom
                comp.enable_default_taskset();
                comp.disable_custom_taskset();
            else
                % Get custom taskset
                [name, identifier, events] = comp.get_custom_taskset();
                
                % Update settings with the value of custom fields
                comp.update_settings(name, identifier, events);

                % Enable custom and disable taskset
                comp.disable_default_taskset();
                comp.enable_custom_taskset();
            end
        end

        % Value changed function: CustomIdentifierField
        function CustomIdentifierFieldValueChanged(comp, event)
            comp.settings.identifier = comp.CustomIdentifierField.Value;
        end

        % Value changed function: CustomEvent1Field
        function CustomEvent1FieldValueChanged(comp, event)
            comp.settings.events(1) = comp.CustomEvent1Field.Value;
        end

        % Value changed function: CustomEvent2Field
        function CustomEvent2FieldValueChanged(comp, event)
            comp.settings.events(2) = comp.CustomEvent2Field.Value;
        end

        % Value changed function: CustomNameField
        function CustomNameFieldValueChanged(comp, event)
            comp.settings.name = comp.CustomNameField.Value;
        end

        % Value changed function: TasksetIdentifierField
        function TasksetIdentifierFieldValueChanged(comp, event)
            comp.settings.identifier = comp.TasksetIdentifierField.Value;
            
        end

        % Value changed function: TasksetEvent1Field
        function TasksetEvent1FieldValueChanged(comp, event)
            comp.settings.events(1) = comp.TasksetEvent1Field.Value;
            
        end

        % Value changed function: TasksetEvent2Field
        function TasksetEvent2FieldValueChanged(comp, event)
            comp.settings.events(2) = comp.TasksetEvent2Field.Value;            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 500 120];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout19 = uigridlayout(comp);
            comp.TasksPanel = uipanel(comp.GridLayout19);
            comp.TasksPanel.Title = 'Tasks';
            comp.GridLayout18 = uigridlayout(comp.TasksPanel);
            comp.TasksetLabel = uilabel(comp.GridLayout18);
            comp.TasksetLabel.Text = 'name';
            comp.TasksetDropDown = uidropdown(comp.GridLayout18);
            comp.TasksetDropDown.Items = {};
            comp.TasksetDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TasksetDropDownValueChanged, true);
            comp.TasksetDropDown.Value = {};
            comp.IdentifierLabel = uilabel(comp.GridLayout18);
            comp.IdentifierLabel.Text = 'identifier';
            comp.EventLabel1 = uilabel(comp.GridLayout18);
            comp.EventLabel1.Text = 'event';
            comp.EventLabel2 = uilabel(comp.GridLayout18);
            comp.EventLabel2.Text = 'event';
            comp.TasksetEvent1Field = uieditfield(comp.GridLayout18, 'numeric');
            comp.TasksetEvent1Field.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TasksetEvent1FieldValueChanged, true);
            comp.TasksetEvent2Field = uieditfield(comp.GridLayout18, 'numeric');
            comp.TasksetEvent2Field.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TasksetEvent2FieldValueChanged, true);
            comp.TasksetIdentifierField = uieditfield(comp.GridLayout18, 'text');
            comp.TasksetIdentifierField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TasksetIdentifierFieldValueChanged, true);
            comp.CustomNameField = uieditfield(comp.GridLayout18, 'text');
            comp.CustomNameField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CustomNameFieldValueChanged, true);
            comp.CustomNameField.Enable = 'off';
            comp.CustomNameField.Placeholder = 'Custom taskset name';
            comp.CustomIdentifierField = uieditfield(comp.GridLayout18, 'text');
            comp.CustomIdentifierField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CustomIdentifierFieldValueChanged, true);
            comp.CustomIdentifierField.Enable = 'off';
            comp.CustomEvent1Field = uieditfield(comp.GridLayout18, 'numeric');
            comp.CustomEvent1Field.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CustomEvent1FieldValueChanged, true);
            comp.CustomEvent1Field.Enable = 'off';
            comp.CustomEvent2Field = uieditfield(comp.GridLayout18, 'numeric');
            comp.CustomEvent2Field.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CustomEvent2FieldValueChanged, true);
            comp.CustomEvent2Field.Enable = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end